classdef visualizeParameters < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure              matlab.ui.Figure
        GridLayout            matlab.ui.container.GridLayout
        LeftPanel             matlab.ui.container.Panel
        editFieldnumF         matlab.ui.control.NumericEditField
        maxFHzLabel_2         matlab.ui.control.Label
        editFieldmaxF         matlab.ui.control.NumericEditField
        maxFHzLabel           matlab.ui.control.Label
        editFieldminF         matlab.ui.control.NumericEditField
        minFHzEditFieldLabel  matlab.ui.control.Label
        editFieldRsolB        matlab.ui.control.NumericEditField
        editFieldRsolA        matlab.ui.control.NumericEditField
        editFieldCb           matlab.ui.control.NumericEditField
        editFieldCa           matlab.ui.control.NumericEditField
        editFieldRs           matlab.ui.control.NumericEditField
        editFieldRb           matlab.ui.control.NumericEditField
        EditFieldLabel_2      matlab.ui.control.Label
        editFieldRa           matlab.ui.control.NumericEditField
        EditFieldLabel        matlab.ui.control.Label
        sliderRsolB           matlab.ui.control.Slider
        RaLabel_7             matlab.ui.control.Label
        sliderRsolA           matlab.ui.control.Slider
        RaLabel_6             matlab.ui.control.Label
        sliderCb              matlab.ui.control.Slider
        RaLabel_5             matlab.ui.control.Label
        sliderCa              matlab.ui.control.Slider
        RaLabel_4             matlab.ui.control.Label
        sliderRs              matlab.ui.control.Slider
        RaLabel_3             matlab.ui.control.Label
        sliderRb              matlab.ui.control.Slider
        RaLabel_2             matlab.ui.control.Label
        sliderRa              matlab.ui.control.Slider
        RaLabel               matlab.ui.control.Label
        RightPanel            matlab.ui.container.Panel
        ZrAxis                matlab.ui.control.UIAxes
        imagAxis              matlab.ui.control.UIAxes
        realAxis              matlab.ui.control.UIAxes
    end

    % Properties that correspond to apps with auto-reflow
    properties (Access = private)
        onePanelWidth = 576;
    end

    
    methods (Access = private)
        
        function refreshApp(app)
            % assign values 
            Ra = 10^app.sliderRa.Value;
            Rb = 10^app.sliderRb.Value;
            Rs = 10^app.sliderRs.Value;
            Ca = 10^app.sliderCa.Value;
            Cb = 10^app.sliderCb.Value;
            RsolA = 10^app.sliderRsolA.Value;
            RsolB = 10^app.sliderRsolB.Value;

            % update display of actual value
            app.editFieldRa.Value = Ra;
            app.editFieldRb.Value = Rb;
            app.editFieldRs.Value = Rs;
            app.editFieldCa.Value = Ca;
            app.editFieldCb.Value = Cb;
            app.editFieldRsolA.Value = RsolA;
            app.editFieldRsolB.Value = RsolB;

            % generate the frequency responses
            f = logspace(log10(app.editFieldminF.Value),log10(app.editFieldmaxF.Value),app.editFieldnumF.Value);
            w = 2.*pi.*f;

            % generate the real, imaginary, and impedance ratio values
            Z = (Ra.*Rs + Rb.*Rs + Ra.*RsolA + Ra.*RsolB + Rb.*RsolA + Rb.*RsolB + Rs.*RsolA + Rs.*RsolB - Ca.*Cb.*Ra.*Rb.*Rs.*RsolA.*w.^2 - Ca.*Cb.*Ra.*Rb.*Rs.*RsolB.*w.^2 + Ca.*Ra.*Rb.*Rs.*w.*1i + Cb.*Ra.*Rb.*Rs.*w.*1i + Ca.*Ra.*Rb.*RsolA.*w.*1i + Ca.*Ra.*Rb.*RsolB.*w.*1i + Cb.*Ra.*Rb.*RsolA.*w.*1i + Cb.*Ra.*Rb.*RsolB.*w.*1i + Ca.*Ra.*Rs.*RsolA.*w.*1i + Ca.*Ra.*Rs.*RsolB.*w.*1i + Cb.*Rb.*Rs.*RsolA.*w.*1i + Cb.*Rb.*Rs.*RsolB.*w.*1i)./(Ra + Rb + Rs - Ca.*Cb.*Ra.*Rb.*Rs.*w.^2 + Ca.*Ra.*Rb.*w.*1i + Cb.*Ra.*Rb.*w.*1i + Ca.*Ra.*Rs.*w.*1i + Cb.*Rb.*Rs.*w.*1i);
            Zratio = sqrt((Ca.^2.*Ra.^2.*RsolA.^2.*w.^2 + Ra.^2 + 2.*Ra.*RsolA + RsolA.^2)./(Ca.^2.*Ra.^2.*w.^2 + 1))./sqrt((Cb.^2.*Rb.^2.*RsolB.^2.*w.^2 + Rb.^2 + 2.*Rb.*RsolB + RsolB.^2)./(Cb.^2.*Rb.^2.*w.^2 + 1));
    
            zdata(:,1) = real(Z);
            zdata(:,2) = imag(Z);
            zdata(:,3) = Zratio;

            % updat the axis
            plot(app.realAxis,log10(f),zdata(:,1),'o');
            plot(app.imagAxis,log10(f),zdata(:,2),'o');
            plot(app.ZrAxis,log10(f),zdata(:,3),'o');

            
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            % update all front panel information
            refreshApp(app);
        end

        % Value changed function: sliderRa
        function sliderRaValueChanged(app, event)
            value = app.sliderRa.Value;
            refreshApp(app);
        end

        % Value changed function: sliderRb
        function sliderRbValueChanged(app, event)
            value = app.sliderRb.Value;
            refreshApp(app);
        end

        % Value changed function: sliderRs
        function sliderRsValueChanged(app, event)
            value = app.sliderRs.Value;
            refreshApp(app);
        end

        % Value changed function: sliderCa
        function sliderCaValueChanged(app, event)
            value = app.sliderCa.Value;
            refreshApp(app);
        end

        % Value changed function: sliderCb
        function sliderCbValueChanged(app, event)
            value = app.sliderCb.Value;
            refreshApp(app);
        end

        % Value changed function: sliderRsolA
        function sliderRsolAValueChanged(app, event)
            value = app.sliderRsolA.Value;
            refreshApp(app);
        end

        % Value changed function: sliderRsolB
        function sliderRsolBValueChanged(app, event)
            value = app.sliderRsolB.Value;
            refreshApp(app);
        end

        % Value changed function: editFieldminF
        function editFieldminFValueChanged(app, event)
            value = app.editFieldminF.Value;
            refreshApp(app);
        end

        % Value changed function: editFieldmaxF
        function editFieldmaxFValueChanged(app, event)
            value = app.editFieldmaxF.Value;
            refreshApp(app);
        end

        % Value changed function: editFieldnumF
        function editFieldnumFValueChanged(app, event)
            value = app.editFieldnumF.Value;
            refreshApp(app);
        end

        % Changes arrangement of the app based on UIFigure width
        function updateAppLayout(app, event)
            currentFigureWidth = app.UIFigure.Position(3);
            if(currentFigureWidth <= app.onePanelWidth)
                % Change to a 2x1 grid
                app.GridLayout.RowHeight = {918, 918};
                app.GridLayout.ColumnWidth = {'1x'};
                app.RightPanel.Layout.Row = 2;
                app.RightPanel.Layout.Column = 1;
            else
                % Change to a 1x2 grid
                app.GridLayout.RowHeight = {'1x'};
                app.GridLayout.ColumnWidth = {322, '1x'};
                app.RightPanel.Layout.Row = 1;
                app.RightPanel.Layout.Column = 2;
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.SizeChangedFcn = createCallbackFcn(app, @updateAppLayout, true);
            app.GridLayout = uigridlayout(app.UIFigure);
            app.LeftPanel = uipanel(app.GridLayout);
            app.RaLabel = uilabel(app.LeftPanel);
            app.RaLabel.Text = 'Ra';
            app.sliderRa = uislider(app.LeftPanel);
            app.sliderRa.Limits = [0 5];
            app.sliderRa.ValueChangedFcn = createCallbackFcn(app, @sliderRaValueChanged, true);
            app.sliderRa.Value = 3;
            app.RaLabel_2 = uilabel(app.LeftPanel);
            app.RaLabel_2.Text = 'Rb';
            app.sliderRb = uislider(app.LeftPanel);
            app.sliderRb.Limits = [0 5];
            app.sliderRb.ValueChangedFcn = createCallbackFcn(app, @sliderRbValueChanged, true);
            app.sliderRb.Value = 3;
            app.RaLabel_3 = uilabel(app.LeftPanel);
            app.RaLabel_3.Text = 'Rs';
            app.sliderRs = uislider(app.LeftPanel);
            app.sliderRs.Limits = [0 5];
            app.sliderRs.ValueChangedFcn = createCallbackFcn(app, @sliderRsValueChanged, true);
            app.sliderRs.Value = 2;
            app.RaLabel_4 = uilabel(app.LeftPanel);
            app.RaLabel_4.Text = 'Ca';
            app.sliderCa = uislider(app.LeftPanel);
            app.sliderCa.Limits = [-9 -4];
            app.sliderCa.ValueChangedFcn = createCallbackFcn(app, @sliderCaValueChanged, true);
            app.sliderCa.Value = -6;
            app.RaLabel_5 = uilabel(app.LeftPanel);
            app.RaLabel_5.Text = 'Cb';
            app.sliderCb = uislider(app.LeftPanel);
            app.sliderCb.Limits = [-9 -4];
            app.sliderCb.ValueChangedFcn = createCallbackFcn(app, @sliderCbValueChanged, true);
            app.sliderCb.Value = -6;
            app.RaLabel_6 = uilabel(app.LeftPanel);
            app.RaLabel_6.Text = 'RsolA';
            app.sliderRsolA = uislider(app.LeftPanel);
            app.sliderRsolA.Limits = [0 3];
            app.sliderRsolA.ValueChangedFcn = createCallbackFcn(app, @sliderRsolAValueChanged, true);
            app.sliderRsolA.Value = 1;
            app.RaLabel_7 = uilabel(app.LeftPanel);
            app.RaLabel_7.Text = 'RsolA';
            app.sliderRsolB = uislider(app.LeftPanel);
            app.sliderRsolB.Limits = [0 3];
            app.sliderRsolB.ValueChangedFcn = createCallbackFcn(app, @sliderRsolBValueChanged, true);
            app.sliderRsolB.Value = 1;
            app.EditFieldLabel = uilabel(app.LeftPanel);
            app.EditFieldLabel.Text = '';
            app.editFieldRa = uieditfield(app.LeftPanel, 'numeric');
            app.editFieldRa.Editable = 'off';
            app.EditFieldLabel_2 = uilabel(app.LeftPanel);
            app.EditFieldLabel_2.Text = '';
            app.editFieldRb = uieditfield(app.LeftPanel, 'numeric');
            app.editFieldRb.Editable = 'off';
            app.editFieldRs = uieditfield(app.LeftPanel, 'numeric');
            app.editFieldRs.Editable = 'off';
            app.editFieldCa = uieditfield(app.LeftPanel, 'numeric');
            app.editFieldCa.Editable = 'off';
            app.editFieldCb = uieditfield(app.LeftPanel, 'numeric');
            app.editFieldCb.Editable = 'off';
            app.editFieldRsolA = uieditfield(app.LeftPanel, 'numeric');
            app.editFieldRsolA.Editable = 'off';
            app.editFieldRsolB = uieditfield(app.LeftPanel, 'numeric');
            app.editFieldRsolB.Editable = 'off';
            app.minFHzEditFieldLabel = uilabel(app.LeftPanel);
            app.minFHzEditFieldLabel.Text = 'minF (Hz)';
            app.editFieldminF = uieditfield(app.LeftPanel, 'numeric');
            app.editFieldminF.Limits = [0 Inf];
            app.editFieldminF.ValueChangedFcn = createCallbackFcn(app, @editFieldminFValueChanged, true);
            app.editFieldminF.Value = 0.5;
            app.maxFHzLabel = uilabel(app.LeftPanel);
            app.maxFHzLabel.Text = 'maxF (Hz)';
            app.editFieldmaxF = uieditfield(app.LeftPanel, 'numeric');
            app.editFieldmaxF.Limits = [0 Inf];
            app.editFieldmaxF.ValueDisplayFormat = '%11.0f';
            app.editFieldmaxF.ValueChangedFcn = createCallbackFcn(app, @editFieldmaxFValueChanged, true);
            app.editFieldmaxF.Value = 10000;
            app.maxFHzLabel_2 = uilabel(app.LeftPanel);
            app.maxFHzLabel_2.Text = 'numF';
            app.editFieldnumF = uieditfield(app.LeftPanel, 'numeric');
            app.editFieldnumF.Limits = [0 Inf];
            app.editFieldnumF.ValueDisplayFormat = '%11.0f';
            app.editFieldnumF.ValueChangedFcn = createCallbackFcn(app, @editFieldnumFValueChanged, true);
            app.editFieldnumF.Value = 100;
            app.RightPanel = uipanel(app.GridLayout);
            app.realAxis = uiaxes(app.RightPanel);
            ylabel(app.realAxis, 'Real')
            zlabel(app.realAxis, 'Z')
            app.imagAxis = uiaxes(app.RightPanel);
            ylabel(app.imagAxis, 'Imag')
            zlabel(app.imagAxis, 'Z')
            app.ZrAxis = uiaxes(app.RightPanel);
            ylabel(app.ZrAxis, 'Zr')
            zlabel(app.ZrAxis, 'Z')
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = visualizeParameters

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end